classdef beyzaapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FiltrelemePanel_2        matlab.ui.container.Panel
        AdaptifEsiklemeButton    matlab.ui.control.Button
        KenarBulSobelButton      matlab.ui.control.Button
        BlurringButton           matlab.ui.control.Button
        GaussButton              matlab.ui.control.Button
        MorfolojiklemlerPanel    matlab.ui.container.Panel
        AmaButton                matlab.ui.control.Button
        AnmaButton               matlab.ui.control.Button
        KapamaButton             matlab.ui.control.Button
        GenilemeButton           matlab.ui.control.Button
        AritmetiklemlerPanel     matlab.ui.container.Panel
        GrntarpmaButton          matlab.ui.control.Button
        GrntToplamaButton        matlab.ui.control.Button
        GrltPanel                matlab.ui.container.Panel
        MedianGrltTemizleButton  matlab.ui.control.Button
        MeanGrltTemizleButton    matlab.ui.control.Button
        GrltEklemeButton         matlab.ui.control.Button
        HistogramPanel           matlab.ui.container.Panel
        GeniletmeButton          matlab.ui.control.Button
        GermeButton              matlab.ui.control.Button
        GiriHistogramButton      matlab.ui.control.Button
        DzenlemePanel            matlab.ui.container.Panel
        ParlaklikArtirmaButton   matlab.ui.control.Button
        YaklstrUzaklsButton      matlab.ui.control.Button
        KrpmaButton              matlab.ui.control.Button
        DndrmeButton             matlab.ui.control.Button
        DnmlerPanel              matlab.ui.container.Panel
        RenkUzayiButton          matlab.ui.control.Button
        BinaryButton             matlab.ui.control.Button
        GriButton                matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        Resim2SeButton           matlab.ui.control.Button
        FiltreIslemleriLabel     matlab.ui.control.Label
        TemizleButton            matlab.ui.control.Button
        ResimSeButton            matlab.ui.control.Button
        GrntlemeLabel            matlab.ui.control.Label
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        img % Description
        img2 % Description
        img3
        imgBinary % Binary görüntüyü tutmak için özellik
        imgRotated
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResimSeButton
        function resimSec(app, event)
            [file, path] = uigetfile('*.*'); %herhangi bir dosya türünü seçebilirim ama tabi görsel seç
            if isequal(file,0) %seçimi iptal ettiyse
                figure(app.UIFigure); %eski normal yeri göstersin
                return;
            end
            figure(app.UIFigure);% resim tüm pencerenin üstünde dursun, arkada kalmasın diye
            app.img = imread(fullfile(path,file)); %resmi okuyorum
            title(app.UIAxes, "Orijinal Resim");
            imshow(app.img, 'parent',app.UIAxes); %app.UIAxes Y ekseni
        end

        % Button pushed function: GriButton
        function griDonustur(app, event)
            if isempty(app.img)
                % Eğer resim yüklenmemişse, kullanıcıyı uyar
                errordlg('Lütfen önce bir resim seçin.', 'Hata', 'modal');
                return;
            end
            % RGB görüntüyü oku
            rgbImage = app.img;
            % mean ile her pikselin RGB değerinin ortalamsını alarak gri görüntüyü elde etme
            % 3. boyutu (RGB renk kanalları) sıarsıyla yükseklik, genişlik ve renk kanallarıdır.
            %grayImage = mean(rgbImage, 3);
            grayImage = FonkRenkli2gri(rgbImage);
            app.img2 = uint8(grayImage);
            % Görüntüyü ekranda göster
            title(app.UIAxes2, "Gri Dönüşüm");
            imshow(app.img2, 'parent', app.UIAxes2);

        end

        % Button pushed function: TemizleButton
        function temizle(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.img = "";
            app.img2= "";
            app.img3= "";
            title(app.UIAxes," ");
            title(app.UIAxes2," ")
            title(app.UIAxes3," ")
        end

        % Button pushed function: BinaryButton
        function binaryDonusum(app, event)
            rgbImage = app.img;
            app.imgBinary = FonkBinary(rgbImage);
            % Görüntüyü ekranda göster
            title(app.UIAxes2, "Binary Dönüşüm");
            imshow(app.imgBinary, 'parent', app.UIAxes2);
        end

        % Button pushed function: DndrmeButton
        function goruntuDondurme(app, event)
            prompt = {'Döndürme Açısını Girin:'};
            dlgtitle = 'Döndürme Açısı:'; %dlgtitle: Giriş penceresinin başlığı.
            dims = [1 35];%dims: Giriş penceresinin boyutları. İlk değer satır sayısını, ikinci değer sütun sayısını temsil eder.
            definput = {'30'};
            donusAcisi = inputdlg(prompt, dlgtitle, dims, definput);
            donusAcisi = str2double(donusAcisi{1}); % Hücre dizisinden çıkarıp double'a dönüştür
            rotationAngle=deg2rad(donusAcisi);
            app.imgRotated = FonkDondurme(app.img, rotationAngle);
            figure;
            subplot(1,2,1);title('Orijinal Resim');imshow(app.img);
            subplot(1,2,2);title('Dondurulmus Resim');imshow(app.imgRotated);
            %title(app.UIAxes2, 'Döndürülmüş Resim');
            %imshow(app.imgRotated, 'parent', app.UIAxes2);
        end

        % Button pushed function: KrpmaButton
        function goruntuKirpma(app, event)
            % Kullanıcıdan kırpma değerlerini almak için bir giriş penceresi göster
            %Kullanıcıya görünen metinleri içeren bir hücre dizisi. Her bir metin, bir giriş alanının etiketini temsil eder.
            prompt = {'Başlangıç X:', 'Başlangıç Y:', 'Bitiş X Ekseni:', 'Bitiş Y Ekseni:'};
            dlgtitle = 'Kırpma Değerleri'; %dlgtitle: Giriş penceresinin başlığı.
            dims = [1 35];%dims: Giriş penceresinin boyutları. İlk değer satır sayısını, ikinci değer sütun sayısını temsil eder.
            %Bitiş X değeri için varsayılan değer, resmin sütun sayısı (app.img'nin ikinci boyutu).
            %Bitiş Y değeri için varsayılan değer, resmin satır sayısı (app.img'nin birinci boyutu).
            definput = {'1', '1', num2str(size(app.img, 2)), num2str(size(app.img, 1))};
            cropValues = inputdlg(prompt, dlgtitle, dims, definput);
            % Alınan değerlere göre kırpma işlemi yap
            %boş değilse Eğer kullanıcı değer girdiyse
            if ~isempty(cropValues)
                %hücre dizisinden gelen string kullanıcı girişlerini double türüne dönüştürüp
                startX = str2double(cropValues{1});
                startY = str2double(cropValues{2});
                endX = str2double(cropValues{3});
                endY = str2double(cropValues{4});

                croppedImage = FonkCropImage(app.img, startX, startY, endX, endY);

                % Kırpılmış görüntüyü göster
                title(app.UIAxes2, 'Kırpılmış Resim');
                imshow(croppedImage, 'parent', app.UIAxes2);
            end
        end

        % Button pushed function: RenkUzayiButton
        function renkUzayiDonusumleri(app, event)
            % Orijinal görüntüyü göster
            subplot(1, 4, 1); imshow(app.img);title('Orijinal Görüntü');
            % HSV dönüşümü
            hsv_img = FonkRGB2HSV(app.img);
            subplot(1, 4, 2);imshow(hsv_img);title('HSV Dönüşümü');
            % YUV dönüşümü
            yuv_img = FonkRGB2YUV(app.img);
            subplot(1, 4, 3);imshow(yuv_img); title('YUV Dönüşümü');
            % Lab dönüşümü
            lab_img = FonkRGB2LAB(app.img);
            subplot(1, 4, 4);imshow(lab_img); title('LAB Dönüşümü');
        end

        % Button pushed function: GrntToplamaButton
        function goruntuToplama(app, event)
            resultImage=FonkToplamaIslemi(app.img, app.img3);
            title(app.UIAxes2, 'Toplama Sonucu');
            imshow(resultImage, 'parent', app.UIAxes2);
        end

        % Button pushed function: GrntarpmaButton
        function goruntuCarpma(app, event)
            resultImage=FonkCarpmaIslemi(app.img, app.img3);
            title(app.UIAxes2, 'Carpma Sonucu');
            imshow(resultImage, 'parent', app.UIAxes2);
        end

        % Button pushed function: Resim2SeButton
        function resim2Sec(app, event)
            [file, path] = uigetfile('*.*'); %herhangi bir dosya türünü seçebilirim ama tabi görsel seç
            if isequal(file,0) %seçimi iptal ettiyse
                figure(app.UIFigure); %eski normal yeri göstersin
                return;
            end
            figure(app.UIFigure);% resim tüm pencerenin üstünde dursun, arkada kalmasın diye
            app.img3 = imread(fullfile(path,file)); %resmi okuyorum
            title(app.UIAxes3, "Orijinal Resim");
            imshow(app.img3, 'parent',app.UIAxes3); %app.UIAxes Y ekseni
        end

        % Button pushed function: ParlaklikArtirmaButton
        function parlaklikArtirma(app, event)
            parlakResim=FonkParlaklikArtirma(app.img);
            title(app.UIAxes2, 'Parlaklığı Artırılmış Resim');
            imshow(parlakResim, 'parent',app.UIAxes2);
        end

        % Button pushed function: AdaptifEsiklemeButton
        function adaptifEsikleme(app, event)
% Orijinal görüntünün boyutunu al
% Görüntünün boyutlarını al,
img = app.img;

% Görüntünün boyutlarını al
[height, width, ~] = size(img);

% Görüntüyü yatayda dört eşit parçaya bölmek için gereken genişlik
segment_width = width / 4;

% Yatayda dört eşit parçaya bölen ilk segmenti al
segment1 = img(:, 1:segment_width, :);

% Yatayda dört eşit parçaya bölen ikinci segmenti al
segment2 = img(:, segment_width+1:2*segment_width, :);

% Yatayda dört eşit parçaya bölen üçüncü segmenti al
segment3 = img(:, 2*segment_width+1:3*segment_width, :);

% Yatayda dört eşit parçaya bölen dördüncü segmenti al
segment4 = img(:, 3*segment_width+1:end, :);

% Her bir segmentte eşikleme yap
g1 = amele_Graythresh(segment1);
g2 = amele_Graythresh(segment2);
g3 = amele_Graythresh(segment3);
g4 = amele_Graythresh(segment4);

result = [g1, g2, g3, g4];

% Her bir segmenti aynı figure içinde göster
figure;
subplot(1,4,1);
imshow(g1);
title('Segment 1');
subplot(1,4,2);
imshow(g2);
title('Segment 2');
subplot(1,4,3);
imshow(g3);
title('Segment 3');
subplot(1,4,4);
imshow(g4);
title('Segment 4');

figure;
imshow(result);

% Her bir parça için adaptif eşikleme uygula ve görselleştir

%{
            *********
            c = app.img;

    % Görüntüyü dört parçaya böler
    [M, N] = size(c);
    col_width = N / 4;

    p1 = c(:, 1:col_width);
    p2 = c(:, col_width+1:2*col_width);
    p3 = c(:, 2*col_width+1:3*col_width);
    p4 = c(:, 3*col_width+1:4*col_width);
    

    %croppedImage = FonkCropImage(app.img, startX, startY, endX,endY);
    % eksen y ve eksen x olarak
    crop1 = FonkCropImage(app.img, 1, 1,M,N/4);
    crop2 = FonkCropImage(app.img, N/4+1,1,M,N/2);
    crop3 = FonkCropImage(app.img, N/2+1,1,M, 3*N/4);
    crop4 = FonkCropImage(app.img, 3*N/4+1,1,M,N);

    figure;
    subplot(1, 4, 1); imshow(crop1);
    subplot(1, 4, 2); imshow(crop2);
    subplot(1, 4, 3); imshow(crop3);
    subplot(1, 4, 4); imshow(crop4);

    ***********
    
crop1 = FonkCropImage(app.img, 1, 1, M, N/4);
    crop2 = FonkCropImage(app.img, 1, N/4+1, M, N/2);
    crop3 = FonkCropImage(app.img, 1, N/2+1, M, 3*N/4);
    crop4 = FonkCropImage(app.img, 1, 3*N/4+1, M, N);
figure;
    subplot(1, 4, 1); imshow(c);
    subplot(1, 4, 2); imshow(c);
    subplot(1, 4, 3); imshow(c);
    subplot(1, 4, 4); imshow(c);
    %}
    % Dört parça için adaptif eşikleme yapar
  
            %{ 
 g1 = adaptiveThreshold(crop1);
    g2 = adaptiveThreshold(crop2);
    g3 = adaptiveThreshold(crop3);
    g4 = adaptiveThreshold(crop4);

    % Parçaları birleştirir
    result = [g1, g2, g3, g4];

    % Sonucu görselleştirir
    figure;
    subplot(1, 2, 1); imshow(c); title('Orijinal');
    subplot(1, 2, 2); imshow(result); title('Adaptif Eşikleme');
   c = app.img ;%imread('circles.png');%app.img;

    % Görüntüyü dört parçaya böler
    [M, N] = size(c);
    p1 = c(:,1:N/4);
    p2 = c(:,N/4+1:N/2);
    p3 = c(:,N/2+1:3*N/4);
    p4 = c(:,3*N/4+1:end);

    % Dört parça için adaptif eşikleme yapar
    g1 = adaptiveThreshold(p1);
    g2 = adaptiveThreshold(p2);
    g3 = adaptiveThreshold(p3);
    g4 = adaptiveThreshold(p4);

    % Sonuçları görselleştirir
    figure;
    subplot(1, 2, 1); imshow(c); title('Orijinal');
    subplot(1, 2, 2); imshow([g1 g2 g3 g4]); title('Adaptif Eşikleme');
    %}
        end

        % Button pushed function: YaklstrUzaklsButton
        function yaklastirmaUzaklastirma(app, event)
            % Kullanıcıdan zooming faktörünü al
            prompt = {'Zooming Factor:'};
            dlgtitle = 'Yaklaştırma';
            dims = [1 35];
            scaleFactorStr = inputdlg(prompt, dlgtitle, dims); % Kullanıcı tarafından girilen değeri double'a çevir
            scaleFactor = str2double(scaleFactorStr{1});
            if isnan(scaleFactor) || scaleFactor <= 0% Hata kontrolü: Geçersiz faktör girişi
                errordlg('Geçersiz zooming faktörü. Lütfen pozitif bir sayı girin.', 'Hata', 'modal');
                return;
            end
            figure;imshow(app.img); title('Resmin Orjinal Hali');
            figure;zoomedImage = FonkYakinlastirUzak(app.img, 1/scaleFactor);imshow(zoomedImage); title('Uzaklaştırılmış Görüntü 1/ölçek');
            figure;zoomedImage = FonkYakinlastirUzak(app.img, scaleFactor); imshow(zoomedImage); title(' Yakınlaştırılmış Görüntü');
        end

        % Button pushed function: GiriHistogramButton
        function girisHistogram(app, event)
            histogram = FonkHistogramGoster(app.img);

            % Histogramı görselleştir
            figure;
            subplot(1,2,1); imshow(app.img); title('Orijinal Görüntü');
            subplot(1,2,2);
            bar( 0:255, histogram, 'BarWidth', 1);
            title('Görüntü Histogramı');
            xlabel('Piksel Değeri');
            ylabel('Piksel Sayısı');
        end

        % Button pushed function: GermeButton
        function histogramGerme(app, event)
            a = 50; % Çıkış aralığının minimum değeri
            b = 200; % Çıkış aralığının maksimum değeri
            histGerme = FonkHistogramGerme(app.img,a,b);% Histogram germe işlemini uygulayın
            histGermeSonuc = reshape(histGerme, [], 1);%2 boyutlu dizide tutsun
            figure;
            subplot(1,3,1); imshow(app.img); title('Orijinal Görüntü');
            subplot(1,3,2); imshow(histGerme); title('Histogram Germe');
            subplot(1,3,3); histogram(histGermeSonuc, 'BinEdges', 0:255, 'DisplayName', 'Histogram Germe');
            title('Histogram Germe');
            xlabel('Piksel Değeri');
            ylabel('Piksel Sayısı');
        end

        % Button pushed function: GeniletmeButton
        function histogramGenisletme(app, event)
            histGenisletme = FonkHistogramGenislet(app.img);
            %histGenisletmeSonuc = reshape(histGenisletme, [], 1);%2 boyutlu dizide tutsun
            figure;
            subplot(1,3,1); imshow(app.img); title('Orijinal Görüntü');
            subplot(1,3,2); imshow(histGenisletme); title('Histogram Genisletme');
            subplot(1,3,3);histogram(histGenisletme, 'BinEdges', 0:255, 'DisplayName', 'Histogram Germe');
            title('Histogram Genisletme');
            xlabel('Piksel Değeri');
            ylabel('Piksel Sayısı');
        end

        % Button pushed function: KenarBulSobelButton
        function sobel(app, event)

            %Sobelimg=FonkRenkli2gri(app.img);
            Sobl= imread ('peppers.png');
            Sobelimg=rgb2gray(Sobl);
            img=double(Sobelimg);
            for i=1:size(img,1)-2
                for j=1:size(img,2)-2
                    %X yönü için Sobel maskesi
                    Gx=((2*img(i+2,j+1)+img(i+2,j)+img(i+2,j+2)-(2*img(i,j+1)+img(i,j)+img(i,j+2))));
                    %Y yönü için Sobel maskesi
                    Gy=((2*img(i+1,j+2)+img(i,j+2)+img(i+2,j+2))-(2*img(i+1,j)+img(i,j)+img(i+2,j)));
                    %Görüntünün gradyanı
                    Sobelimg(i,j)= sqrt(Gx.^2+Gy.^2);
                end
            end
            figure,
            subplot(1,2,1);imshow (Sobl); title ('Orijinal Resim');
            subplot(1,2,2);imshow (Sobelimg); title ('Sobel gradient');
        end

        % Button pushed function: GrltEklemeButton
        function gurultuEkle(app, event)
            % Gürültü seviyesini al
            prompt = {'Salt & Peppers:'};
            dlgtitle = 'Gurultu Ekleme';
            dims = [1 35];
            gurultuSeviyesiStr = inputdlg(prompt, dlgtitle, dims); % Kullanıcı tarafından girilen değeri double'a çevir
            gurultuSeviyesi = str2double(gurultuSeviyesiStr{1});

            % Gürültü eklenen görüntüyü al
            gurultuluGoruntu = FonksaltAndPepperNoise(app.img, gurultuSeviyesi);

            % Görüntüyü göster
            imshow(gurultuluGoruntu, 'Parent', app.UIAxes2);
            title(app.UIAxes2, 'Gürültülü Görüntü');
        end

        % Button pushed function: MeanGrltTemizleButton
        function mean(app, event)
            % Gürültülü görüntüyü al
            gurultuluGoruntu = app.img;

            % Temizleme filtresinin boyutunu ve tipini al
            prompt = {'Mean:'};
            dlgtitle = 'Mean Gurultu Temizleme';
            dims = [1 35];
            filtreBoyutuStr = inputdlg(prompt, dlgtitle, dims); % Kullanıcı tarafından girilen değeri double'a çevir
            filtreBoyutu = str2double(filtreBoyutuStr{1});


            % Gürültülü görüntüyü ortalama filtre ile temizle
            temizlenmisGoruntu = FonkmeanFilter(gurultuluGoruntu, filtreBoyutu);

            % Temizlenmiş görüntüyü göster
            imshow(temizlenmisGoruntu, 'Parent', app.UIAxes2);
            title(app.UIAxes2, 'Mean Temizlenmiş Görüntü');
        end

        % Button pushed function: MedianGrltTemizleButton
        function median(app, event)
            gurultuluGoruntu = app.img;

            prompt = {'Median:'};
            dlgtitle = 'Median Gurultu Temizleme';
            dims = [1 35];
            filtreBoyutuStr = inputdlg(prompt, dlgtitle, dims); % Kullanıcı tarafından girilen değeri double'a çevir
            filtreBoyutu = str2double(filtreBoyutuStr{1});

            % Gürültülü görüntüyü medyan filtre ile temizle
            temizlenmisGoruntu = FonkMedianFilter(gurultuluGoruntu, filtreBoyutu);

            % Temizlenmiş görüntüyü göster
            imshow(temizlenmisGoruntu, 'Parent', app.UIAxes2);
            title(app.UIAxes2, 'Median Temizlenmiş Görüntü');
        end

        % Button pushed function: BlurringButton
        function blurring(app, event)
            windowHalfWidth = 9;
            temizlenmisGoruntu = FonkBlurringFilter(app.img, windowHalfWidth);
            imshow(temizlenmisGoruntu, 'Parent', app.UIAxes2);
            title(app.UIAxes2, 'Blurring Filtre Uygulanmış Görüntü');
        end

        % Button pushed function: GaussButton
        function gauss(app, event)
            output=FonkGaussKonvolusyon(app.img);
            imshow(output, 'Parent', app.UIAxes2);
            title(app.UIAxes2, 'output of gaussian filter');
        end

        % Button pushed function: GenilemeButton
        function morfolojikGenisleme(app, event)
            a=imread('text.png');
            genislemeSonuc=FonkMorfoGenisleme(a);
            title(app.UIAxes2, 'Morfolojik Genişleme');
            imshow(genislemeSonuc, 'parent', app.UIAxes2);

        end

        % Button pushed function: AnmaButton
        function morfolojikAsinma(app, event)
            a=imread('text.png');
            D=FonkMorfoAsinma(a);
            title(app.UIAxes2, 'Morfolojik Aşınma');
            imshow(D, 'parent', app.UIAxes2);
        end

        % Button pushed function: AmaButton
        function morfolojikAcma(app, event)
            a=imread('snowflakes.png');
            imshow('snowflakes.png');
            D=FonkMorfoAcma(a);
            title(app.UIAxes2, 'Morfolojik Acma');
            imshow(D, 'parent', app.UIAxes2);
        end

        % Button pushed function: KapamaButton
        function morfolojikKapama(app, event)
            a=imread('snowflakes.png');
            imshow('snowflakes.png');
            D=FonkMorfoKapama(a);
            title(app.UIAxes2, 'Morfolojik Kapama');
            imshow(D, 'parent', app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Orijinal')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, {'Filtrelenmiş'; ''})
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.Visible = 'off';
            app.GrntlemeLabel = uilabel(app.UIFigure);
            app.GrntlemeLabel.Text = 'Görüntü İşleme';
            app.ResimSeButton = uibutton(app.UIFigure, 'push');
            app.ResimSeButton.ButtonPushedFcn = createCallbackFcn(app, @resimSec, true);
            app.ResimSeButton.Text = 'Resim Seç';
            app.TemizleButton = uibutton(app.UIFigure, 'push');
            app.TemizleButton.ButtonPushedFcn = createCallbackFcn(app, @temizle, true);
            app.TemizleButton.Text = 'Temizle';
            app.FiltreIslemleriLabel = uilabel(app.UIFigure);
            app.FiltreIslemleriLabel.Text = 'Resim seçtikten sonra uygulamak istediğiniz filtreye göre butonlara basarak işleminizi gerçekleştirebilirsiniz...';
            app.Resim2SeButton = uibutton(app.UIFigure, 'push');
            app.Resim2SeButton.ButtonPushedFcn = createCallbackFcn(app, @resim2Sec, true);
            app.Resim2SeButton.Text = 'Resim2 Seç';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DnmlerPanel = uipanel(app.UIFigure);
            app.DnmlerPanel.Title = 'Dönüşümler';
            app.GriButton = uibutton(app.DnmlerPanel, 'push');
            app.GriButton.ButtonPushedFcn = createCallbackFcn(app, @griDonustur, true);
            app.GriButton.Text = 'Gri';
            app.BinaryButton = uibutton(app.DnmlerPanel, 'push');
            app.BinaryButton.ButtonPushedFcn = createCallbackFcn(app, @binaryDonusum, true);
            app.BinaryButton.Text = 'Binary';
            app.RenkUzayiButton = uibutton(app.DnmlerPanel, 'push');
            app.RenkUzayiButton.ButtonPushedFcn = createCallbackFcn(app, @renkUzayiDonusumleri, true);
            app.RenkUzayiButton.Text = 'Renk Uzayi';
            app.DzenlemePanel = uipanel(app.UIFigure);
            app.DzenlemePanel.Title = 'Düzenleme';
            app.DndrmeButton = uibutton(app.DzenlemePanel, 'push');
            app.DndrmeButton.ButtonPushedFcn = createCallbackFcn(app, @goruntuDondurme, true);
            app.DndrmeButton.Text = 'Döndürme';
            app.KrpmaButton = uibutton(app.DzenlemePanel, 'push');
            app.KrpmaButton.ButtonPushedFcn = createCallbackFcn(app, @goruntuKirpma, true);
            app.KrpmaButton.Text = 'Kırpma';
            app.YaklstrUzaklsButton = uibutton(app.DzenlemePanel, 'push');
            app.YaklstrUzaklsButton.ButtonPushedFcn = createCallbackFcn(app, @yaklastirmaUzaklastirma, true);
            app.YaklstrUzaklsButton.Text = 'YaklstrUzakls';
            app.ParlaklikArtirmaButton = uibutton(app.DzenlemePanel, 'push');
            app.ParlaklikArtirmaButton.ButtonPushedFcn = createCallbackFcn(app, @parlaklikArtirma, true);
            app.ParlaklikArtirmaButton.Text = 'Parlaklik Artirma';
            app.HistogramPanel = uipanel(app.UIFigure);
            app.HistogramPanel.Title = 'Histogram';
            app.GiriHistogramButton = uibutton(app.HistogramPanel, 'push');
            app.GiriHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @girisHistogram, true);
            app.GiriHistogramButton.Text = 'Giriş Histogram';
            app.GermeButton = uibutton(app.HistogramPanel, 'push');
            app.GermeButton.ButtonPushedFcn = createCallbackFcn(app, @histogramGerme, true);
            app.GermeButton.Text = 'Germe';
            app.GeniletmeButton = uibutton(app.HistogramPanel, 'push');
            app.GeniletmeButton.ButtonPushedFcn = createCallbackFcn(app, @histogramGenisletme, true);
            app.GeniletmeButton.Text = 'Genişletme';
            app.GrltPanel = uipanel(app.UIFigure);
            app.GrltPanel.Title = 'Gürültü';
            app.GrltEklemeButton = uibutton(app.GrltPanel, 'push');
            app.GrltEklemeButton.ButtonPushedFcn = createCallbackFcn(app, @gurultuEkle, true);
            app.GrltEklemeButton.Text = 'Gürültü Ekleme';
            app.MeanGrltTemizleButton = uibutton(app.GrltPanel, 'push');
            app.MeanGrltTemizleButton.ButtonPushedFcn = createCallbackFcn(app, @mean, true);
            app.MeanGrltTemizleButton.Text = 'Mean Grlt Temizle';
            app.MedianGrltTemizleButton = uibutton(app.GrltPanel, 'push');
            app.MedianGrltTemizleButton.ButtonPushedFcn = createCallbackFcn(app, @median, true);
            app.MedianGrltTemizleButton.Text = 'Median Grlt Temizle';
            app.AritmetiklemlerPanel = uipanel(app.UIFigure);
            app.AritmetiklemlerPanel.Title = 'Aritmetik İşlemler';
            app.GrntToplamaButton = uibutton(app.AritmetiklemlerPanel, 'push');
            app.GrntToplamaButton.ButtonPushedFcn = createCallbackFcn(app, @goruntuToplama, true);
            app.GrntToplamaButton.Text = 'Görüntü Toplama';
            app.GrntarpmaButton = uibutton(app.AritmetiklemlerPanel, 'push');
            app.GrntarpmaButton.ButtonPushedFcn = createCallbackFcn(app, @goruntuCarpma, true);
            app.GrntarpmaButton.Text = 'Görüntü Çarpma';
            app.MorfolojiklemlerPanel = uipanel(app.UIFigure);
            app.MorfolojiklemlerPanel.Title = 'Morfolojik İşlemler';
            app.GenilemeButton = uibutton(app.MorfolojiklemlerPanel, 'push');
            app.GenilemeButton.ButtonPushedFcn = createCallbackFcn(app, @morfolojikGenisleme, true);
            app.GenilemeButton.Text = 'Genişleme';
            app.KapamaButton = uibutton(app.MorfolojiklemlerPanel, 'push');
            app.KapamaButton.ButtonPushedFcn = createCallbackFcn(app, @morfolojikKapama, true);
            app.KapamaButton.Text = 'Kapama';
            app.AnmaButton = uibutton(app.MorfolojiklemlerPanel, 'push');
            app.AnmaButton.ButtonPushedFcn = createCallbackFcn(app, @morfolojikAsinma, true);
            app.AnmaButton.Text = 'Aşınma';
            app.AmaButton = uibutton(app.MorfolojiklemlerPanel, 'push');
            app.AmaButton.ButtonPushedFcn = createCallbackFcn(app, @morfolojikAcma, true);
            app.AmaButton.Text = 'Açma';
            app.FiltrelemePanel_2 = uipanel(app.UIFigure);
            app.FiltrelemePanel_2.Title = 'Filtreleme';
            app.GaussButton = uibutton(app.FiltrelemePanel_2, 'push');
            app.GaussButton.ButtonPushedFcn = createCallbackFcn(app, @gauss, true);
            app.GaussButton.Text = 'Gauss';
            app.BlurringButton = uibutton(app.FiltrelemePanel_2, 'push');
            app.BlurringButton.ButtonPushedFcn = createCallbackFcn(app, @blurring, true);
            app.BlurringButton.Text = 'Blurring';
            app.KenarBulSobelButton = uibutton(app.FiltrelemePanel_2, 'push');
            app.KenarBulSobelButton.ButtonPushedFcn = createCallbackFcn(app, @sobel, true);
            app.KenarBulSobelButton.Text = 'Kenar Bul Sobel';
            app.AdaptifEsiklemeButton = uibutton(app.FiltrelemePanel_2, 'push');
            app.AdaptifEsiklemeButton.ButtonPushedFcn = createCallbackFcn(app, @adaptifEsikleme, true);
            app.AdaptifEsiklemeButton.Text = 'Adaptif Esikleme';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beyzaapp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end